classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NoisereducingCheckBox    matlab.ui.control.CheckBox
        Labelerror               matlab.ui.control.Label
        filenameEditField        matlab.ui.control.EditField
        filenameEditFieldLabel   matlab.ui.control.Label
        mp4DropDown              matlab.ui.control.DropDown
        mp4DropDownLabel         matlab.ui.control.Label
        TimeyouwanttoshowinoneframeLabel  matlab.ui.control.Label
        NotethatthismaycauselessperformanceLabel  matlab.ui.control.Label
        MakethegraphsyncwithaudioCheckBox  matlab.ui.control.CheckBox
        TimeScaleEditField       matlab.ui.control.NumericEditField
        TimeScaleEditFieldLabel  matlab.ui.control.Label
        SaveButton               matlab.ui.control.Button
        ConfirmButton            matlab.ui.control.Button
        Label_8                  matlab.ui.control.Label
        Millisecdigit0_2         matlab.ui.control.Label
        Millisecdigit1_2         matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Seconddigit0_2           matlab.ui.control.Label
        Minutedigit1_2           matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Seconddigit1_2           matlab.ui.control.Label
        Minutedigit0_2           matlab.ui.control.Label
        EqualizerLabel           matlab.ui.control.Label
        Slidertime               matlab.ui.control.Slider
        shiftPitchSlider         matlab.ui.control.Slider
        shiftPitchSliderLabel    matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        kHzSlider_5Label         matlab.ui.control.Label
        kHzSlider_5              matlab.ui.control.Slider
        kHzSlider_4Label         matlab.ui.control.Label
        kHzSlider_4              matlab.ui.control.Slider
        kHzSlider_3Label         matlab.ui.control.Label
        kHzSlider_3              matlab.ui.control.Slider
        kHzSlider_2Label         matlab.ui.control.Label
        kHzSlider_2              matlab.ui.control.Slider
        kHzSliderLabel           matlab.ui.control.Label
        kHzSlider                matlab.ui.control.Slider
        HzSlider_5Label          matlab.ui.control.Label
        HzSlider_5               matlab.ui.control.Slider
        HzSlider_4Label          matlab.ui.control.Label
        HzSlider_4               matlab.ui.control.Slider
        HzSlider_3Label          matlab.ui.control.Label
        HzSlider_3               matlab.ui.control.Slider
        HzSlider_2Label          matlab.ui.control.Label
        HzSlider_2               matlab.ui.control.Slider
        Millisecdigit0           matlab.ui.control.Label
        Millisecdigit1           matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Seconddigit0             matlab.ui.control.Label
        Minutedigit1             matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Seconddigit1             matlab.ui.control.Label
        Minutedigit0             matlab.ui.control.Label
        HzSlider                 matlab.ui.control.Slider
        HzSliderLabel            matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        PathLabel                matlab.ui.control.Label
        stopButton               matlab.ui.control.Button
        BrowsefilesButton        matlab.ui.control.Button
        ResumeButton             matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu_2                   matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
    end

    
    properties (Access = public)
        fullpath = NaN;
        Samplerate = NaN;
        Audiodata = NaN;
        player = NaN;
        State_pause = 1;
        equalizer = graphicEQ("Gains",[0,0,0,0,0,0,0,0,0,0])
        endtime = 10;
        time = 0;
    end
    
    methods (Access = private)


    end
    
    methods (Access = public)
        function timing(app,~,~)
            if app.player.CurrentSample/app.Samplerate < app.endtime-0.04
                app.Slidertime.Value = app.player.CurrentSample/app.Samplerate;
                min = floor(app.Slidertime.Value/60);
                sec = mod(app.Slidertime.Value,60);
                millisec = mod(sec,floor(sec))*100;
                app.Minutedigit1_2.Text = string(floor(min/10));
                app.Minutedigit0_2.Text = string(mod(min,10));
    
                app.Seconddigit1_2.Text = string(floor(sec/10));
                app.Seconddigit0_2.Text = string(floor(mod(sec,10)));
    
                app.Millisecdigit1_2.Text = string(floor(millisec/10));
                app.Millisecdigit0_2.Text = string(floor(mod(millisec,10)));
                if app.MakethegraphsyncwithaudioCheckBox.Value == true
                    app.UIAxes.XLim = [(app.player.CurrentSample/app.Samplerate)-app.TimeScaleEditField.Value app.player.CurrentSample/app.Samplerate];
                end
            else
                pause(app.player);
                app.State_pause = 1;
                app.ResumeButton.Text = "Resume";            
                app.Slidertime.Value = app.endtime;
                min = floor(app.Slidertime.Value/60);
                sec = mod(app.Slidertime.Value,60);
                millisec = mod(sec,floor(sec))*100;
                app.Minutedigit1_2.Text = string(floor(min/10));
                app.Minutedigit0_2.Text = string(mod(min,10));
    
                app.Seconddigit1_2.Text = string(floor(sec/10));
                app.Seconddigit0_2.Text = string(floor(mod(sec,10)));
    
                app.Millisecdigit1_2.Text = string(floor(millisec/10));
                app.Millisecdigit0_2.Text = string(floor(mod(millisec,10)));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

                
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            try
                app.Labelerror.Text = '';
                stop(app.player);
                play(app.player);
                app.State_pause = 0;
                app.ResumeButton.Text = "Pause";
            catch
                app.Labelerror.Text = 'Insert your audio';
            end
            
        end

        % Button pushed function: BrowsefilesButton
        function BrowsefilesButtonPushed(app, event)
            [filename,path] = uigetfile({'*.m4a;*.mp4;*.mp3;*.wav;*.opus;*.ogg;*.flac;*.au;*.aiff;*.aif;*.aifc'},'Select One Files');
            app.fullpath = [path filename];
            app.NoisereducingCheckBox.Value = false;
            app.shiftPitchSlider.Value = 0;
            app.HzSlider.Value = 0;
            app.HzSlider_2.Value = 0;
            app.HzSlider_3.Value = 0;
            app.HzSlider_4.Value = 0;
            app.HzSlider_5.Value = 0;
            app.kHzSlider.Value = 0;
            app.kHzSlider_2.Value = 0;
            app.kHzSlider_3.Value = 0;
            app.kHzSlider_4.Value = 0;
            app.kHzSlider_5.Value = 0;
            app.equalizer = graphicEQ("Gains",[0,0,0,0,0,0,0,0,0,0]);
            app.player = NaN;
            app.Samplerate = NaN;
            app.Audiodata = NaN;

            app.Slidertime.Value = 0;
            app.Minutedigit1_2.Text = '0';
            app.Minutedigit0_2.Text = '0';
    
            app.Seconddigit1_2.Text = '0';
            app.Seconddigit0_2.Text = '0';
    
            app.Millisecdigit1_2.Text = '0';
            app.Millisecdigit0_2.Text = '0';
            try 
                app.fullpath == [0 0];
                app.Label.Text = "";
                app.Labelerror.Text = 'Insert your audio';
            catch
                app.Labelerror.Text = '';
                app.Label.Text = app.fullpath;
                [y,Fs] = audioread(app.fullpath);
                app.Samplerate = Fs;
                app.Audiodata = y;
                app.player = audioplayer(app.Audiodata ,app.Samplerate);
                
                get(app.player);
                app.player.TimerFcn = @app.timing;
                app.player.TimerPeriod = 0.01;
                Times = length(app.Audiodata)/app.Samplerate;
                t = linspace(0, Times, length(app.Audiodata));
                app.UIAxes.XLim = [0 length(app.Audiodata)/app.Samplerate];
                plot(app.UIAxes,t,y);
                app.endtime = Times;
                app.Slidertime.Limits = [0,Times];
                min = floor(Times/60);
                sec = mod(Times,60);
                millisec = mod(sec,floor(sec))*100;
                app.Minutedigit1.Text = string(floor(min/10));
                app.Minutedigit0.Text = string(mod(min,10));

                app.Seconddigit1.Text = string(floor(sec/10));
                app.Seconddigit0.Text = string(floor(mod(sec,10)));

                app.Millisecdigit1.Text = string(floor(millisec/10));
                app.Millisecdigit0.Text = string(floor(mod(millisec,10)));
                
            end
            app.State_pause = 1;
            app.ResumeButton.Text = "Resume";
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
              if app.State_pause == 0
                  app.Labelerror.Text = '';
                  pause(app.player);
                  app.State_pause = 1;
                  app.ResumeButton.Text = "Resume";
              else
                  try
                    app.Labelerror.Text = '';
                    resume(app.player);
                    app.State_pause = 0;
                    app.ResumeButton.Text = "Pause";
                  catch
                    [y,Fs] = audioread("error sound.mp3");
                    play(audioplayer(y ,Fs));
                  end
              end
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            try
                app.Labelerror.Text = '';
                app.Slidertime.Value = 0;
                app.Minutedigit1_2.Text = '0';
                app.Minutedigit0_2.Text = '0';
        
                app.Seconddigit1_2.Text = '0';
                app.Seconddigit0_2.Text = '0';
        
                app.Millisecdigit1_2.Text = '0';
                app.Millisecdigit0_2.Text = '0';
                stop(app.player);
                app.Slidertime.Value = 0;
                app.State_pause = 1;
                app.ResumeButton.Text = "Resume";
                app.UIAxes.XLim = [0 length(app.Audiodata)/app.Samplerate];
            catch
                app.Labelerror.Text = 'Insert your audio';
            end
        end

        % Value changed function: HzSlider
        function HzSliderValueChanged(app, event)
            value = app.HzSlider.Value;
%             app.equalizer.Gains(1) = value; 
        end

        % Value changing function: HzSlider
        function HzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(1) = changingValue;
        end

        % Value changed function: HzSlider_2
        function HzSlider_2ValueChanged(app, event)
            value = app.HzSlider_2.Value;
%             app.equalizer.Gains(2) = value; 
        end

        % Value changed function: HzSlider_3
        function HzSlider_3ValueChanged(app, event)
            value = app.HzSlider_3.Value;
%             app.equalizer.Gains(3) = value; 
        end

        % Value changed function: HzSlider_4
        function HzSlider_4ValueChanged(app, event)
            value = app.HzSlider_4.Value;
%             app.equalizer.Gains(4) = value; 
        end

        % Value changed function: HzSlider_5
        function HzSlider_5ValueChanged(app, event)
            value = app.HzSlider_5.Value;
%             app.equalizer.Gains(5) = value; 
        end

        % Value changed function: kHzSlider
        function kHzSliderValueChanged(app, event)
            value = app.kHzSlider.Value;
%             app.equalizer.Gains(6) = value; 
        end

        % Value changed function: kHzSlider_2
        function kHzSlider_2ValueChanged(app, event)
            value = app.kHzSlider_2.Value;
%             app.equalizer.Gains(7) = value; 
        end

        % Value changed function: kHzSlider_3
        function kHzSlider_3ValueChanged(app, event)
            value = app.kHzSlider_3.Value;
%             app.equalizer.Gains(8) = value; 
        end

        % Value changed function: kHzSlider_4
        function kHzSlider_4ValueChanged(app, event)
            value = app.kHzSlider_4.Value;
%             app.equalizer.Gains(9) = value; 
        end

        % Value changed function: kHzSlider_5
        function kHzSlider_5ValueChanged(app, event)
            value = app.kHzSlider_5.Value;
%             app.equalizer.Gains(10) = value; 
        end

        % Value changing function: HzSlider_2
        function HzSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(2) = changingValue;
        end

        % Value changing function: HzSlider_3
        function HzSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(3) = changingValue;

        end

        % Value changing function: HzSlider_4
        function HzSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(4) = changingValue;
        end

        % Value changing function: HzSlider_5
        function HzSlider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(5) = changingValue;
        end

        % Value changing function: kHzSlider
        function kHzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(6) = changingValue;

        end

        % Value changing function: kHzSlider_2
        function kHzSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(7) = changingValue;
        end

        % Value changing function: kHzSlider_3
        function kHzSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(8) = changingValue;

        end

        % Value changing function: kHzSlider_4
        function kHzSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(9) = changingValue;

        end

        % Value changing function: kHzSlider_5
        function kHzSlider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(10) = changingValue;

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             try
                app.Labelerror.Text = '';
                app.NoisereducingCheckBox.Value = false;
                app.shiftPitchSlider.Value = 0;
                app.HzSlider.Value = 0;
                app.HzSlider_2.Value = 0;
                app.HzSlider_3.Value = 0;
                app.HzSlider_4.Value = 0;
                app.HzSlider_5.Value = 0;
                app.kHzSlider.Value = 0;
                app.kHzSlider_2.Value = 0;
                app.kHzSlider_3.Value = 0;
                app.kHzSlider_4.Value = 0;
                app.kHzSlider_5.Value = 0;
                app.equalizer.Gains = [0,0,0,0,0,0,0,0,0,0];
                y=app.equalizer(app.Audiodata);
                y = shiftPitch(y,app.shiftPitchSlider.Value);
                app.player = audioplayer(y ,app.Samplerate);
                app.player.TimerFcn = @app.timing;
                app.player.TimerPeriod = 0.01;
                play(app.player,app.player.SampleRate*round(app.Slidertime.Value));
%                 pause(app.player);
%                 app.State_pause = 1;
                app.ResumeButton.Text = "Pause";
                t = linspace(0, length(app.Audiodata)/app.Samplerate, length(app.Audiodata));
                if app.MakethegraphsyncwithaudioCheckBox.Value == true
                    app.UIAxes.XLim = [(app.player.CurrentSample/app.Samplerate)-app.TimeScaleEditField.Value app.player.CurrentSample/app.Samplerate];
                else
                    app.UIAxes.XLim = [0 length(app.Audiodata)/app.Samplerate];
                end
                plot(app.UIAxes,t,y);
            catch
                app.Labelerror.Text = 'Insert your audio';
            end

        end

        % Value changing function: Slidertime
        function SlidertimeValueChanging(app, event)
            changingValue = event.Value;
            pause(app.player);
            app.State_pause = 1;
            app.ResumeButton.Text = "Resume"; 
            min = floor(changingValue/60);
            sec = mod(changingValue,60);
            millisec = mod(sec,floor(sec))*100;
            app.Minutedigit1_2.Text = string(floor(min/10));
            app.Minutedigit0_2.Text = string(mod(min,10));
    
            app.Seconddigit1_2.Text = string(floor(sec/10));
            app.Seconddigit0_2.Text = string(floor(mod(sec,10)));
    
            app.Millisecdigit1_2.Text = string(floor(millisec/10));
            app.Millisecdigit0_2.Text = string(floor(mod(millisec,10)));
            
        end

        % Value changed function: Slidertime
        function SlidertimeValueChanged(app, event)
            value = app.Slidertime.Value;
            try
                app.Labelerror.Text = '';
                stop(app.player);
                play(app.player,app.player.SampleRate*round(value));
                app.State_pause = 0;
                app.ResumeButton.Text = "Pause";
            catch
                app.Labelerror.Text = 'Insert your audio';
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            try
                app.Labelerror.Text = '';
%                 pause(app.player);
                app.State_pause = 1;
                app.ResumeButton.Text = "Pause";
                y = app.equalizer(app.Audiodata);
                y = shiftPitch(y,app.shiftPitchSlider.Value);
                if app.NoisereducingCheckBox.Value == true
                    order = 7;
                    [b,a] = butter(order,900/(app.Samplerate/2),'low');
                    y = filter(b,a,y);
                end
                app.player = audioplayer(y ,app.Samplerate);          
                app.player.TimerFcn = @app.timing;
                app.player.TimerPeriod = 0.01;
                play(app.player,app.player.SampleRate*round(app.Slidertime.Value));
                pause(app.player);
                t = linspace(0, length(app.Audiodata)/app.Samplerate, length(app.Audiodata));
                if app.MakethegraphsyncwithaudioCheckBox.Value == true
                    app.UIAxes.XLim = [(app.player.CurrentSample/app.Samplerate)-app.TimeScaleEditField.Value app.player.CurrentSample/app.Samplerate];
                else
                    app.UIAxes.XLim = [0 length(app.Audiodata)/app.Samplerate];
                end
                resume(app.player);
                plot(app.UIAxes,t,y);
            catch
                app.Labelerror.Text = 'Insert your audio';
            end
        end

        % Value changed function: MakethegraphsyncwithaudioCheckBox
        function MakethegraphsyncwithaudioCheckBoxValueChanged(app, event)
            value = app.MakethegraphsyncwithaudioCheckBox.Value;
            try
                app.Labelerror.Text = '';
                if value == false 
                    app.UIAxes.XLim = [0 length(app.Audiodata)/app.Samplerate];
                else
                    app.UIAxes.XLim = [(app.player.CurrentSample/app.Samplerate)-app.TimeScaleEditField.Value app.player.CurrentSample/app.Samplerate];
                end
            catch
                app.Labelerror.Text = 'Insert your audio';
            end    
        end

        % Value changed function: TimeScaleEditField
        function TimeScaleEditFieldValueChanged(app, event)
            value = app.TimeScaleEditField.Value;
            if value <= 0 
               app.TimeScaleEditField.Value = 0.05;
            end
            try
                app.Labelerror.Text = '';
                if app.MakethegraphsyncwithaudioCheckBox.Value == true
                   app.UIAxes.XLim = [(app.player.CurrentSample/app.Samplerate)-app.TimeScaleEditField.Value app.player.CurrentSample/app.Samplerate];
                end
            catch
                app.Labelerror.Text = 'Insert your audio';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           try
                app.Labelerror.Text = '';
                path = [app.filenameEditField.Value app.mp4DropDown.Value];
                y=app.equalizer(app.Audiodata);
                y = shiftPitch(y,app.shiftPitchSlider.Value);
                if app.NoisereducingCheckBox.Value == true
                    order = 7;
                    [b,a] = butter(order,900/(app.Samplerate/2),'low');
                    y = filter(b,a,y);
                end
                audiowrite(path,y,app.Samplerate);

                app.shiftPitchSlider.Value = 0;
                app.NoisereducingCheckBox.Value = false;
    
                app.HzSlider.Value = 0;
                app.HzSlider_2.Value = 0;
                app.HzSlider_3.Value = 0;
                app.HzSlider_4.Value = 0;
                app.HzSlider_5.Value = 0;
                app.kHzSlider.Value = 0;
                app.kHzSlider_2.Value = 0;
                app.kHzSlider_3.Value = 0;
                app.kHzSlider_4.Value = 0;
                app.kHzSlider_5.Value = 0;
                app.equalizer = graphicEQ("Gains",[0,0,0,0,0,0,0,0,0,0]);
                app.player = NaN;
                app.Samplerate = NaN;
                app.Audiodata = NaN;
    
                app.Slidertime.Value = 0;
                app.Slidertime.Limits = [0,1];

                app.Minutedigit1_2.Text = '0';
                app.Minutedigit0_2.Text = '0';
        
                app.Seconddigit1_2.Text = '0';
                app.Seconddigit0_2.Text = '0';
        
                app.Millisecdigit1_2.Text = '0';
                app.Millisecdigit0_2.Text = '0';

                app.Minutedigit1.Text = '0';
                app.Minutedigit0.Text = '0';
        
                app.Seconddigit1.Text = '0';
                app.Seconddigit0.Text = '0';
        
                app.Millisecdigit1.Text = '0';
                app.Millisecdigit0.Text = '0';
    
                app.Label.Text = "";
                cla(app.UIAxes);
                app.UIAxes.XLim = [0 1];
           catch
                app.Labelerror.Text = 'Insert your audio';
           end
        end

        % Value changed function: mp4DropDown
        function mp4DropDownValueChanged(app, event)
            value = app.mp4DropDown.Value;
            app.mp4DropDownLabel.Text = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time')
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, 'Audio Signal')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.BrowsefilesButton = uibutton(app.UIFigure, 'push');
            app.BrowsefilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefilesButtonPushed, true);
            app.BrowsefilesButton.Text = 'Browse files';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.HzSliderLabel = uilabel(app.UIFigure);
            app.HzSliderLabel.Text = '31.5 Hz';
            app.HzSlider = uislider(app.UIFigure);
            app.HzSlider.Limits = [-15 15];
            app.HzSlider.ValueChangedFcn = createCallbackFcn(app, @HzSliderValueChanged, true);
            app.HzSlider.ValueChangingFcn = createCallbackFcn(app, @HzSliderValueChanging, true);
            app.Minutedigit0 = uilabel(app.UIFigure);
            app.Minutedigit0.Text = '0';
            app.Seconddigit1 = uilabel(app.UIFigure);
            app.Seconddigit1.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = ':';
            app.Minutedigit1 = uilabel(app.UIFigure);
            app.Minutedigit1.Text = '0';
            app.Seconddigit0 = uilabel(app.UIFigure);
            app.Seconddigit0.Text = '0';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = ':';
            app.Millisecdigit1 = uilabel(app.UIFigure);
            app.Millisecdigit1.Text = '0';
            app.Millisecdigit0 = uilabel(app.UIFigure);
            app.Millisecdigit0.Text = '0';
            app.HzSlider_2 = uislider(app.UIFigure);
            app.HzSlider_2.Limits = [-15 15];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @HzSlider_2ValueChanged, true);
            app.HzSlider_2.ValueChangingFcn = createCallbackFcn(app, @HzSlider_2ValueChanging, true);
            app.HzSlider_2Label = uilabel(app.UIFigure);
            app.HzSlider_2Label.Text = '63 Hz';
            app.HzSlider_3 = uislider(app.UIFigure);
            app.HzSlider_3.Limits = [-15 15];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @HzSlider_3ValueChanged, true);
            app.HzSlider_3.ValueChangingFcn = createCallbackFcn(app, @HzSlider_3ValueChanging, true);
            app.HzSlider_3Label = uilabel(app.UIFigure);
            app.HzSlider_3Label.Text = '125 Hz';
            app.HzSlider_4 = uislider(app.UIFigure);
            app.HzSlider_4.Limits = [-15 15];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @HzSlider_4ValueChanged, true);
            app.HzSlider_4.ValueChangingFcn = createCallbackFcn(app, @HzSlider_4ValueChanging, true);
            app.HzSlider_4Label = uilabel(app.UIFigure);
            app.HzSlider_4Label.Text = '250 Hz';
            app.HzSlider_5 = uislider(app.UIFigure);
            app.HzSlider_5.Limits = [-15 15];
            app.HzSlider_5.ValueChangedFcn = createCallbackFcn(app, @HzSlider_5ValueChanged, true);
            app.HzSlider_5.ValueChangingFcn = createCallbackFcn(app, @HzSlider_5ValueChanging, true);
            app.HzSlider_5Label = uilabel(app.UIFigure);
            app.HzSlider_5Label.Text = '500 Hz';
            app.kHzSlider = uislider(app.UIFigure);
            app.kHzSlider.Limits = [-15 15];
            app.kHzSlider.ValueChangedFcn = createCallbackFcn(app, @kHzSliderValueChanged, true);
            app.kHzSlider.ValueChangingFcn = createCallbackFcn(app, @kHzSliderValueChanging, true);
            app.kHzSliderLabel = uilabel(app.UIFigure);
            app.kHzSliderLabel.Text = '1 kHz';
            app.kHzSlider_2 = uislider(app.UIFigure);
            app.kHzSlider_2.Limits = [-15 15];
            app.kHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_2ValueChanged, true);
            app.kHzSlider_2.ValueChangingFcn = createCallbackFcn(app, @kHzSlider_2ValueChanging, true);
            app.kHzSlider_2Label = uilabel(app.UIFigure);
            app.kHzSlider_2Label.Text = '2 kHz';
            app.kHzSlider_3 = uislider(app.UIFigure);
            app.kHzSlider_3.Limits = [-15 15];
            app.kHzSlider_3.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_3ValueChanged, true);
            app.kHzSlider_3.ValueChangingFcn = createCallbackFcn(app, @kHzSlider_3ValueChanging, true);
            app.kHzSlider_3Label = uilabel(app.UIFigure);
            app.kHzSlider_3Label.Text = '4 kHz';
            app.kHzSlider_4 = uislider(app.UIFigure);
            app.kHzSlider_4.Limits = [-15 15];
            app.kHzSlider_4.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_4ValueChanged, true);
            app.kHzSlider_4.ValueChangingFcn = createCallbackFcn(app, @kHzSlider_4ValueChanging, true);
            app.kHzSlider_4Label = uilabel(app.UIFigure);
            app.kHzSlider_4Label.Text = '8 kHz';
            app.kHzSlider_5 = uislider(app.UIFigure);
            app.kHzSlider_5.Limits = [-15 15];
            app.kHzSlider_5.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_5ValueChanged, true);
            app.kHzSlider_5.ValueChangingFcn = createCallbackFcn(app, @kHzSlider_5ValueChanging, true);
            app.kHzSlider_5Label = uilabel(app.UIFigure);
            app.kHzSlider_5Label.Text = '16 kHz';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.shiftPitchSliderLabel = uilabel(app.UIFigure);
            app.shiftPitchSliderLabel.Text = 'shiftPitch';
            app.shiftPitchSlider = uislider(app.UIFigure);
            app.shiftPitchSlider.Limits = [-12 12];
            app.Slidertime = uislider(app.UIFigure);
            app.Slidertime.Limits = [0 1];
            app.Slidertime.ValueChangedFcn = createCallbackFcn(app, @SlidertimeValueChanged, true);
            app.Slidertime.ValueChangingFcn = createCallbackFcn(app, @SlidertimeValueChanging, true);
            app.EqualizerLabel = uilabel(app.UIFigure);
            app.EqualizerLabel.Text = 'Equalizer';
            app.Minutedigit0_2 = uilabel(app.UIFigure);
            app.Minutedigit0_2.Text = '0';
            app.Seconddigit1_2 = uilabel(app.UIFigure);
            app.Seconddigit1_2.Text = '0';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = ':';
            app.Minutedigit1_2 = uilabel(app.UIFigure);
            app.Minutedigit1_2.Text = '0';
            app.Seconddigit0_2 = uilabel(app.UIFigure);
            app.Seconddigit0_2.Text = '0';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = ':';
            app.Millisecdigit1_2 = uilabel(app.UIFigure);
            app.Millisecdigit1_2.Text = '0';
            app.Millisecdigit0_2 = uilabel(app.UIFigure);
            app.Millisecdigit0_2.Text = '0';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '|';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TimeScaleEditFieldLabel = uilabel(app.UIFigure);
            app.TimeScaleEditFieldLabel.Text = 'Time Scale';
            app.TimeScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeScaleEditField.ValueChangedFcn = createCallbackFcn(app, @TimeScaleEditFieldValueChanged, true);
            app.TimeScaleEditField.Value = 0.5;
            app.MakethegraphsyncwithaudioCheckBox = uicheckbox(app.UIFigure);
            app.MakethegraphsyncwithaudioCheckBox.ValueChangedFcn = createCallbackFcn(app, @MakethegraphsyncwithaudioCheckBoxValueChanged, true);
            app.MakethegraphsyncwithaudioCheckBox.Text = 'Make the graph sync with audio';
            app.NotethatthismaycauselessperformanceLabel = uilabel(app.UIFigure);
            app.NotethatthismaycauselessperformanceLabel.Text = '(Note that this may cause less performance)';
            app.TimeyouwanttoshowinoneframeLabel = uilabel(app.UIFigure);
            app.TimeyouwanttoshowinoneframeLabel.Text = '(Time you want to show in one frame)';
            app.mp4DropDownLabel = uilabel(app.UIFigure);
            app.mp4DropDownLabel.Text = '.mp4';
            app.mp4DropDown = uidropdown(app.UIFigure);
            app.mp4DropDown.Items = {'.mp4', '.wav', '.flac', '.m4a', '.oga'};
            app.mp4DropDown.ValueChangedFcn = createCallbackFcn(app, @mp4DropDownValueChanged, true);
            app.mp4DropDown.Value = '.mp4';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.Labelerror = uilabel(app.UIFigure);
            app.Labelerror.Text = '';
            app.NoisereducingCheckBox = uicheckbox(app.UIFigure);
            app.NoisereducingCheckBox.Text = 'Noise reducing';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end